classdef MeiHuaYiShu1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        KeepinmindTextArea          matlab.ui.control.TextArea
        KeepinmindTextAreaLabel     matlab.ui.control.Label
        Hyperlink2_2                matlab.ui.control.Hyperlink
        Hyperlink2                  matlab.ui.control.Hyperlink
        YaoMeaningNatureFamilyandetcTextArea  matlab.ui.control.TextArea
        YaoMeaningNatureFamilyandetcTextAreaLabel  matlab.ui.control.Label
        FurtherInterpretationLabel  matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        StartButton                 matlab.ui.control.Button
        ResultKnob                  matlab.ui.control.DiscreteKnob
        ResultKnobLabel             matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        Step1Tab                    matlab.ui.container.Tab
        AnalysisButton              matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        TextArea                    matlab.ui.control.TextArea
        Step2Tab                    matlab.ui.container.Tab
        AnalysisButton_2            matlab.ui.control.Button
        Image_2                     matlab.ui.control.Image
        TextArea_2                  matlab.ui.control.TextArea
        Step3Tab                    matlab.ui.container.Tab
        AnalysisButton_3            matlab.ui.control.Button
        Image_3                     matlab.ui.control.Image
        TextArea_3                  matlab.ui.control.TextArea
        Step4Tab                    matlab.ui.container.Tab
        AnalysisButton_4            matlab.ui.control.Button
        Image_4                     matlab.ui.control.Image
        TextArea_4                  matlab.ui.control.TextArea
        Pleaseinputtwo2digitnumbersEditField_2  matlab.ui.control.EditField
        Step0Closeyoureyesandmeditatefor30secondsLabel  matlab.ui.control.Label
        Pleaseinputtwo2digitnumbersEditField  matlab.ui.control.EditField
        Pleaseinputtwo2digitnumbersEditFieldLabel  matlab.ui.control.Label
        FlowerMaysNumbersfromIchingLabel  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        Hyperlink_3                 matlab.ui.control.Hyperlink
        PrerequisitLabel            matlab.ui.control.Label
        Hyperlink_2                 matlab.ui.control.Hyperlink
        Image2                      matlab.ui.control.Image
        PreludeTextArea             matlab.ui.control.TextArea
        PreludeTextAreaLabel        matlab.ui.control.Label
        LanguageSwitch              matlab.ui.control.Switch
        LanguageSwitchLabel         matlab.ui.control.Label
        Hyperlink                   matlab.ui.control.Hyperlink
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)        

    end
    
    properties (Access = public)
        gua = cell(8,12); % Description
        Number1 = '17';
        UpGua = 1;
        UpGuaBinary = '0';
        UpGuaTrigram = '0';
        UpGuaCN = '0';
        UpGuaEN = '0';
        UpGuaMeaning = '0';
        UpGuaNatureEN = '0';
        UpGuaNatureCN = '0';
        UpGuaPhase = '0';
        UpGuaLH = '0';
        UpGuaEH = '0';
        UpGuaFamily = '0';
        Number2 = '18';
        Number3 = 35;
        DownGua = 1;
        DownGuaBinary = '0';
        DownGuaTrigram = '0';
        DownGuaCN = '0';
        DownGuaEN = '0';
        DownGuaMeaning = '0';
        DownGuaNatureEN = '0';
        DownGuaNatureCN = '0';
        DownGuaPhase = '0';
        DownGuaLH = '0';
        DownGuaEH = '0';
        DownGuaFamily = '0';   
        BianGua = 1;
        BianGuaBinary0 = ['000','000'];
        BianGuaBinary1 = ['000','001'];     
        Language = 'EN';
        BianGuaHexagram = ['☷','☷'];
        BianGuaPhase0 = 'Earth';
        BianGuaPhase1 = 'Earth';
        BUC = 'Earth(Utiity) generates Earth(Body) which means Super Lucky.'

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: Pleaseinputtwo2digitnumbersEditField
        function Pleaseinputtwo2digitnumbersEditFieldValueChanged(app, event)
            value = app.Pleaseinputtwo2digitnumbersEditField.Value;
            app.Number1 = value;
%             app.UpGua = mod(str2num(app.Number1),8);
%             app.UpGuaBinary = app.gua(app.UpGua,2);
%             app.UpGuaTrigram = app.gua(app.UpGua,3);
%             app.UpGuaCN = app.gua(app.UpGua,4);
%             app.UpGuaEN = app.gua(app.UpGua,5);
%             app.UpGuaMeaning = app.gua(app.UpGua,6);
%             app.UpGuaNatureEN = app.gua(app.UpGua,7);
%             app.UpGuaNatureCN = app.gua(app.UpGua,8);
%             app.UpGuaPhase = app.gua(app.UpGua,9);
%             app.UpGuaLH = app.gua(app.UpGua,10);
%             app.UpGuaEH = app.gua(app.UpGua,11);
%             app.UpGuaFamily = app.gua(app.UpGua,12);
        end

        % Value changed function: Pleaseinputtwo2digitnumbersEditField_2
        function Pleaseinputtwo2digitnumbersEditField_2ValueChanged(app, event)
            value = app.Pleaseinputtwo2digitnumbersEditField_2.Value;
            app.Number2 = value;
%             app.DownGua = mod(str2num(app.Number2),8);
%             app.DownGuaBinary = app.gua(app.DownGua,2);
%             app.DownGuaTrigram = app.gua(app.DownGua,3);
%             app.DownGuaCN = app.gua(app.DownGua,4);
%             app.DownGuaEN = app.gua(app.DownGua,5);
%             app.DownGuaMeaning = app.gua(app.DownGua,6);
%             app.DownGuaNatureEN = app.gua(app.DownGua,7);
%             app.DownGuaNatureCN = app.gua(app.DownGua,8);
%             app.DownGuaPhase = app.gua(app.DownGua,9);
%             app.DownGuaLH = app.gua(app.DownGua,10);
%             app.DownGuaEH = app.gua(app.DownGua,11);
%             app.DownGuaFamily = app.gua(app.DownGua,12);

%             app.Number3 = str2num(app.Number1)+str2num(app.Number2);
%             app.BianGua = mod(app.Number3,6);
%             app.BianGuaBinary0 = [app.UpGuaBinary,app.DownGuaBinary];
%             app.BianGuaBinary1 = app.BianGuaBinary0;
%  %           app.BianGuaBinary1(app.BianGua) = num2str(~str2num(app.BianGuaBinary0(app.BianGua)));
%             switch app.BianGua
%                 case 0
%                     app.BianGuaBinary1(1) = num2str(~str2num(app.BianGuaBinary0(1)));
%                 otherwise
%                     app.BianGuaBinary1(7-app.BianGua) = num2str(~str2num(app.BianGuaBinary0(7-app.BianGua)));
%             end
% 
%             switch app.BianGuaBinary1
%                 case '000000'
%                     app.BianGuaHexagram = ['☷','☷'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Earth';
%                     app.BUC = 'Earth(Utiity) generates Earth(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky'
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC = '土(用) 生 土(体) 代表大吉。'
%                     end
%                 case '000001'
%                     app.BianGuaHexagram = ['☷','☶'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Earth';     
%                     app.BUC = 'Earth(Utiity) generates Earth(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC = '土(用) 生 土(体) 代表大吉。'
%                     end                    
%                 case '000010'
%                     app.BianGuaHexagram = ['☷','☵'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Water';     
%                     app.BUC = 'Earth(Utiity) overcomes Water(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '水';
%                         app.BUC = '土(用) 克 水(体) 代表大凶。'
%                     end
%                 case '000011'
%                     app.BianGuaHexagram = ['☷','☴'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Wood';     
%                     app.BUC= 'Wood(Body) overcomes Earth(Utiity) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(体) 克 土(用) 代表小吉。'
%                     end
%                 case '000100'
%                     app.BianGuaHexagram = ['☷','☳'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Wood';     
%                     app.BUC= 'Wood(Body) overcomes Earth(Utiity) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(体) 克 土(用) 代表小吉。'
%                     end                    
%                 case '000101'
%                     app.BianGuaHexagram = ['☷','☲'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Fire';     
%                     app.BUC= 'Fire(Body) generates Earth(Utiity) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '火';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '火(体) 生 土(用) 代表小凶。'
%                     end 
%                 case '000110'
%                     app.BianGuaHexagram = ['☷','☱'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Metal';     
%                     app.BUC= 'Earth(Utiity) generates Metal(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(用) 生 金(体) 代表大吉。'
%                     end                     
%                 case '000111'
%                     app.BianGuaHexagram = ['☷','☰'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Metal';     
%                     app.BUC= 'Earth(Utiity) generates Metal(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(用) 生 金(体) 代表大吉。'
%                     end                       
%                 case '001000'
%                     app.BianGuaHexagram = ['☶','☷'];  
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Earth';     
%                     app.BUC= 'Earth(Utiity) generates Earth(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(用) 生 土(体) 代表大吉。'
%                     end                       
%                 case '001001'
%                     app.BianGuaHexagram = ['☶','☶'];    
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Earth';     
%                     app.BUC= 'Earth(Utiity) generates Earth(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(用) 生 土(体) 代表大吉。'
%                     end                     
%                 case '001010'
%                     app.BianGuaHexagram = ['☶','☷'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Water';     
%                     app.BUC= 'Earth(Utiity) overcomes Water(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '水';
%                         app.BUC= '土(用) 生 水(体) 代表大凶。'
%                     end                       
%                 case '001011'
%                     app.BianGuaHexagram = ['☶','☴'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Wood';     
%                     app.BUC= 'Wood(Body) overcomes Earth(Utiity) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(体) 克 土(用) 代表小吉。'
%                     end                       
%                 case '001100'
%                     app.BianGuaHexagram = ['☶','☳'];  
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Wood';     
%                     app.BUC= 'Wood(Body) overcomes Earth(Utiity) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(体) 克 土(用) 代表小吉。'
%                     end               
%                 case '001101'
%                     app.BianGuaHexagram = ['☶','☲'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Fire';     
%                     app.BUC= 'Fire(Body) generates Earth(Utiity) which means Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '火';
%                         app.BUC= '火(体) 生 土(用) 代表小凶。'
%                     end                           
%                 case '001110'
%                     app.BianGuaHexagram = ['☶','☱'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Metal';     
%                     app.BUC= 'Earth(Body) generates Metal(Utiity) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '土(用) 生 金(体) 代表大吉。'
%                     end                     
%                 case '001111'
%                     app.BianGuaHexagram = ['☶','☰'];
%                     app.BianGuaPhase0= 'Earth';
%                     app.BianGuaPhase1 = 'Metal';     
%                     app.BUC= 'Earth(Body) generates Metal(Utiity) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '土';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '土(用) 生 金(体) 代表大吉。'
%                     end                       
%                 case '010000'
%                     app.BianGuaHexagram = ['☵','☷'];  
%                     app.BianGuaPhase0= 'Water';
%                     app.BianGuaPhase1 = 'Earth';     
%                     app.BUC= 'Earth(Body) overcomes Water(Utiity) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '水';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(体) 克 水(用) 代表小吉。'
%                     end                      
%                 case '010001'
%                     app.BianGuaHexagram = ['☵','☷'];   
%                     app.BianGuaPhase0= 'Water';
%                     app.BianGuaPhase1 = 'Earth';     
%                     app.BUC= 'Earth(Body) overcomes Water(Utiity) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '水';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(体) 克 水(用) 代表小吉。'
%                     end                        
%                 case '010010'
%                     app.BianGuaHexagram = ['☵','☵'];
%                     app.BianGuaPhase0= 'Water';
%                     app.BianGuaPhase1 = 'Water';     
%                     app.BUC= 'Water(Utiity) generates Water(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '水';
%                         app.BianGuaPhase1 = '水';
%                         app.BUC= '水(体) 生 水(用) 代表大吉。'
%                     end                     
%                 case '010011'
%                     app.BianGuaHexagram = ['☵','☴'];
%                     app.BianGuaPhase0= 'Water';
%                     app.BianGuaPhase1 = 'Wood';     
%                     app.BUC= 'Water(Utiity) generates Wood(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '水';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '水(体) 生 木(用) 代表大吉。'
%                     end                          
%                 case '010100'
%                     app.BianGuaHexagram = ['☵','☳'];  
%                     app.BianGuaPhase0= 'Water';
%                     app.BianGuaPhase1 = 'Wood';     
%                     app.BUC= 'Water(Utiity) generates Wood(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '水';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '水(体) 生 木(用) 代表大吉。'
%                     end                       
%                 case '010101'
%                     app.BianGuaHexagram = ['☵','☲'];
%                     app.BianGuaPhase0= 'Water';
%                     app.BianGuaPhase1 = 'Fire';     
%                     app.BUC= 'Water(Utiity) overcomes Fire(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '水';
%                         app.BianGuaPhase1 = '火';
%                         app.BUC= '水(用) 克 火(体) 代表大凶。'
%                     end      
%                 case '010110'
%                     app.BianGuaHexagram = ['☵','☱'];
%                     app.BianGuaPhase0= 'Water';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Body) overcomes Water(Utiity) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '水';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 克 水(用) 代表小吉。'
%                     end                        
%                 case '010111'
%                     app.BianGuaHexagram = ['☵','☰'];
%                     app.BianGuaPhase0= 'Water';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Body) overcomes Water(Utiity) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '水';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 克 水(用) 代表小吉。'
%                     end                       
%                 case '011000'
%                     app.BianGuaHexagram = ['☴','☷'];  
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Wood(Utiity) overcomes Earth(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '木(体) 克 土(用) 代表大凶。'
%                     end                      
%                 case '011001'
%                     app.BianGuaHexagram = ['☴','☶'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Wood(Utiity) overcomes Earth(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '木(体) 克 土(用) 代表大凶。'
%                     end                      
%                 case '011010'
%                     app.BianGuaHexagram = ['☴','☵'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Water';      
%                     app.BUC= 'Water(Body) generates Wood(Utiity) which means Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '水';
%                         app.BUC= '木(体) 生 水(用) 代表小凶。'
%                     end                              
%                 case '011011'
%                     app.BianGuaHexagram = ['☴','☴'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Wood(Utility) generates Wood(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(用) 生 木(体) 代表大吉。'
%                     end                        
%                 case '011100'
%                     app.BianGuaHexagram = ['☴','☳'];  
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Wood(Utility) generates Wood(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(用) 生 木(体) 代表大吉。'
%                     end 
%                 case '011101'
%                     app.BianGuaHexagram = ['☴','☳'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Fire';      
%                     app.BUC= 'Wood(Utility) generates Fire(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '火';
%                         app.BUC= '木(用) 生 火(体) 代表大吉。'
%                     end                     
%                 case '011110'
%                     app.BianGuaHexagram = ['☴','☷'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Body) overcomes Wood(Utility) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 克 木(用) 代表小吉。'
%                     end                    
%                 case '011111'
%                     app.BianGuaHexagram = ['☴','☷']; 
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Body) overcomes Wood(Utility) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 克 木(用) 代表小吉。'
%                     end                      
%                 case '100000'
%                     app.BianGuaHexagram = ['☳','☷'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Wood(Utiity) overcomes Earth(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '木(用) 克 土(体) 代表大凶。'
%                     end                      
%                 case '100001'
%                     app.BianGuaHexagram = ['☳','☶'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Wood(Utiity) overcomes Earth(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '木(用) 克 土(体) 代表大凶。'
%                     end                        
%                 case '100010'
%                     app.BianGuaHexagram = ['☳','☵'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Water';      
%                     app.BUC= 'Water(Body) generates Wood(Utiity) which means Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '水';
%                         app.BUC= '木(体) 生 水(用) 代表小凶。'
%                     end                   
%                 case '100011'
%                     app.BianGuaHexagram = ['☳','☴'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Wood(Utility) generates Wood(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(用) 生 木(体) 代表大吉。'
%                     end                     
%                 case '100100'
%                     app.BianGuaHexagram = ['☳','☳'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Wood(Utility) generates Wood(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(用) 生 木(体) 代表大吉。'
%                     end                     
%                 case '100101'
%                     app.BianGuaHexagram = ['☳','☲'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Fire';      
%                     app.BUC= 'Wood(Utility) generates Fire(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '火';
%                         app.BUC= '木(用) 生 火(体) 代表大吉。'
%                     end                     
%                 case '100110'
%                     app.BianGuaHexagram = ['☳','☱'];
%                     app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Body) overcomes Wood(Utility) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 克 木(用) 代表小吉。'
%                     end                      
%                 case '100111'
%                     app.BianGuaHexagram = ['☳','☰'];
%                      app.BianGuaPhase0= 'Wood';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Body) overcomes Wood(Utility) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '木';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 克 木(用) 代表小吉。'
%                     end                     
%                 case '101000'
%                     app.BianGuaHexagram = ['☲','☷'];  
%                     app.BianGuaPhase0= 'Fire';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Fire(Utility) generates Earth(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '火';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '火(用) 生 土(体) 代表大吉。'
%                     end                       
%                 case '101001'
%                     app.BianGuaHexagram = ['☲','☳']; 
%                     app.BianGuaPhase0= 'Fire';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Fire(Utility) generates Earth(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '火';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '火(用) 生 土(体) 代表大吉。'
%                     end                       
%                 case '101010'
%                     app.BianGuaHexagram = ['☲','☵'];
%                     app.BianGuaPhase0= 'Fire';
%                     app.BianGuaPhase1 = 'Water';      
%                     app.BUC= 'Water(Body) overcomes Fire(Utility) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '火';
%                         app.BianGuaPhase1 = '水';
%                         app.BUC= '水(体) 克 火(用) 代表小吉。'
%                     end                       
%                 case '101011'
%                     app.BianGuaHexagram = ['☲','☴'];
%                     app.BianGuaPhase0= 'Fire';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Wood(Body) generates Fire(Utility) which means Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '火';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(体) 生 火(用) 代表小凶。'
%                     end                        
%                 case '101100'
%                     app.BianGuaHexagram = ['☲','☳'];  
%                     app.BianGuaPhase0= 'Fire';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Wood(Body) generates Fire(Utility) which means Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '火';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '木(体) 生 火(用) 代表小凶。'    
%                     end
%                 case '101101'
%                     app.BianGuaHexagram = ['☲','☲'];
%                     app.BianGuaPhase0= 'Fire';
%                     app.BianGuaPhase1 = 'Fire';      
%                     app.BUC= 'Fire(Utility) generates Fire(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '火';
%                         app.BianGuaPhase1 = '火';
%                         app.BUC= '火(用) 生 火(体) 代表大吉。'    
%                     end                   
%                 case '101110'
%                     app.BianGuaHexagram = ['☲','☱'];
%                     app.BianGuaPhase0= 'Fire';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Fire(Utility) overcomes Metal(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '火';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '火(用) 克 金(体) 代表大凶。'    
%                     end                         
%                 case '101111'
%                     app.BianGuaHexagram = ['☲','☰'];
%                     app.BianGuaPhase0= 'Fire';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Fire(Utility) overcomes Metal(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '火';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '火(用) 克 金(体) 代表大凶。'    
%                     end                       
%                 case '110000'
%                     app.BianGuaHexagram = ['☴','☷'];  
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Earth(Body) generates Metal(Utility) which means Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(体) 生 金(用) 代表小凶。'    
%                     end                      
%                 case '110001'
%                     app.BianGuaHexagram = ['☴','☶'];     
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Earth(Body) generates Metal(Utility) which means Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(体) 生 金(用) 代表小凶。'    
%                     end                         
%                 case '110010'
%                     app.BianGuaHexagram = ['☴','☵'];
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Water';      
%                     app.BUC= 'Metal(Utility) generates Water(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '水';
%                         app.BUC= '金(用) 生 水(体) 代表大吉。'    
%                     end                         
%                 case '110011'
%                     app.BianGuaHexagram = ['☴','☴'];
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Metal(Utility) overcomes Wood(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '金(用) 克 木(体) 代表大凶。'    
%                     end                      
%                 case '110100'
%                     app.BianGuaHexagram = ['☴','☳'];  
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Metal(Utility) overcomes Wood(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '金(用) 克 木(体) 代表大凶。'        
%                     end
%                 case '110101'
%                     app.BianGuaHexagram = ['☴','☲'];
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Fire';      
%                     app.BUC= 'Fire(Body) overcomes Metal(Utility) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '火';
%                         app.BUC= '火(体) 克 金(用) 代表小吉。'    
%                     end                    
%                 case '110110'
%                     app.BianGuaHexagram = ['☴','☱'];
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Utility) generates Metal(Utility) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 生 金(用) 代表大吉。'    
%                     end                         
%                 case '110111'
%                     app.BianGuaHexagram = ['☴','☰'];
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Utility) generates Metal(Utility) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 生 金(用) 代表大吉。'    
%                     end                       
%                 case '111000'
%                     app.BianGuaHexagram = ['☰','☷']; 
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Earth(Body) generates Metal(Utility) which means Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(体) 生 金(用) 代表小凶。'    
%                     end                         
%                 case '111001'
%                     app.BianGuaHexagram = ['☰','☶'];  
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Earth';      
%                     app.BUC= 'Earth(Body) generates Metal(Utility) which means Miserable.'
%                     app.ResultKnob.Value = 'Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '土';
%                         app.BUC= '土(体) 生 金(用) 代表小凶。'    
%                     end                         
%                 case '111010'
%                     app.BianGuaHexagram = ['☰','☵'];
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Water';      
%                     app.BUC= 'Metal(Utility) generates Water(Body) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '水';
%                         app.BUC= '金(用) 生 水(体) 代表大吉。'    
%                     end                      
%                 case '111011'
%                     app.BianGuaHexagram = ['☰','☴'];
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Metal(Utility) overcomes Wood(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '金(用) 克 木(体) 代表大凶。'     
%                     end
%                 case '111100'
%                     app.BianGuaHexagram = ['☰','☳'];  
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Wood';      
%                     app.BUC= 'Metal(Utility) overcomes Wood(Body) which means Severe Miserable.'
%                     app.ResultKnob.Value = 'Severe Miserable' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '木';
%                         app.BUC= '金(用) 克 木(体) 代表大凶。'    
%                     end
%                 case '111101'
%                     app.BianGuaHexagram = ['☰','☲'];
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Fire';      
%                     app.BUC= 'Fire(Body) overcomes Metal(Utility) which means Lucky.'
%                     app.ResultKnob.Value = 'Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '火';
%                         app.BUC= '火(体) 克 金(用) 代表小吉。'    
%                     end
%                 case '111110'
%                     app.BianGuaHexagram = ['☰','☱'];
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Utility) generates Metal(Utility) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 生 金(用) 代表大吉。'    
%                     end                       
%                 case '111111'
%                     app.BianGuaHexagram = ['☰','☰'];      
%                     app.BianGuaPhase0= 'Metal';
%                     app.BianGuaPhase1 = 'Metal';      
%                     app.BUC= 'Metal(Utility) generates Metal(Utility) which means Super Lucky.'
%                     app.ResultKnob.Value = 'Super Lucky' 
%                     if app.Language == 'CN'
%                         app.BianGuaPhase0= '金';
%                         app.BianGuaPhase1 = '金';
%                         app.BUC= '金(体) 生 金(用) 代表大吉。'    
%                     end                       
%             end
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            app.UpGua = mod(str2num(app.Number1),8);
            app.UpGuaBinary = app.gua(app.UpGua,2);
            app.UpGuaTrigram = app.gua(app.UpGua,3);
            app.UpGuaCN = app.gua(app.UpGua,4);
            app.UpGuaEN = app.gua(app.UpGua,5);
            app.UpGuaMeaning = app.gua(app.UpGua,6);
            app.UpGuaNatureEN = app.gua(app.UpGua,7);
            app.UpGuaNatureCN = app.gua(app.UpGua,8);
            app.UpGuaPhase = app.gua(app.UpGua,9);
            app.UpGuaLH = app.gua(app.UpGua,10);
            app.UpGuaEH = app.gua(app.UpGua,11);
            app.UpGuaFamily = app.gua(app.UpGua,12);
            if app.Language == 'EN'
                app.TextArea.Value = 'First step:  We compute the UpGua. Please check the left panel for further analysis.'
                app.YaoMeaningNatureFamilyandetcTextAreaLabel.Text = 'UpGua';
                app.YaoMeaningNatureFamilyandetcTextArea.Value = ['Taking your first 2-digit number, after mod calculation, we have the UpGua valued ', num2str(app.Number1), ' which can be binary noted as: ', app.UpGuaBinary, 'according to Leibniz who actually got enlightened from Iching as well.', 'Meanwhile, the original 爻 yáo of it is denoted as: ', app.UpGuaTrigram, '.', ...
                    'The meaning of it is: ', app.UpGuaMeaning, 'And the image of nature of it is: ', app.UpGuaNatureEN, 'Phase: ',  app.UpGuaPhase, 'Later Heaven Direction: ', app.UpGuaLH, 'Ealier Heaven Direction: ', app.UpGuaEH, 'Family Relationship: ', app.UpGuaFamily]
            elseif app.Language == 'CN'
                app.TextArea.Value = '第一步:  我们计算上卦. 请看左侧具体分析.'
                app.YaoMeaningNatureFamilyandetcTextAreaLabel.Text = '上卦';
                app.YaoMeaningNatureFamilyandetcTextArea.Value = ['根据第一个你给出的2位数,经过8卦取模, 我们得到上卦值为：', num2str(app.Number1), '其二进制表示为: ', app.UpGuaBinary, '源于受易经启发的Leibniz所著。', '同时, 原来的 爻 yáo 表示为: ', app.UpGuaTrigram, '.', ...
                    '其含义为: ', app.UpGuaMeaning, '自然画像为: ', app.UpGuaNatureCN, '卦象处于五行: ',  app.UpGuaPhase, '文王诠释落座于: ', app.UpGuaLH, '武王诠释落座于: ', app.UpGuaEH, '家庭角色为: ', app.UpGuaFamily]
            end
%             switch app.UpGua
%                 case 0
%                     fprintf('0, 000, ☷ 坤 kūn, the Receptive, Field, \n Image of nature as: Ground(earth), 地 \n Phase as: Earth \n Later Heaven Direction as:Southwest \n Ealier Heaven Direction as: North \n Family Relationship as: Mother')
%                 case 1
%                     fprintf('1, 001, ☶ 艮 gèn, Keeping Still, Bound, \n Image of nature as: Mountain, 山 \n Phase as: Earth \n Later Heaven Direction as:Northeast \n Ealier Heaven Direction as: Northwest \n Family Relationship as: Third son')
%                 case 2
%                     fprintf('2, 010, ☵ 坎 kǎn, the Abysmal, Gorge, \n Image of nature as: Water, 水 \n Phase as: Water \n Later Heaven Direction as:North \n Ealier Heaven Direction as: West \n Family Relationship as: Second son')
%                 case 3
%                     fprintf('3, 011, ☴ 巽 xùn, the Gentle, Ground, \n Image of nature as: Wind(air), 风 \n Phase as: Wood \n Later Heaven Direction as:Southest \n Ealier Heaven Direction as: Southwest \n Family Relationship as: First daughter')
%                 case 4
%                     fprintf('4, 100, ☳ 震 zhèn, the Arousing, Shake, \n Image of nature as: Thunder, 雷 \n Phase as: Wood \n Later Heaven Direction as:East \n Ealier Heaven Direction as: Northeast \n Family Relationship as: First son')
%                 case 5
%                     fprintf('5, 101, ☲ 離 lí, the Clinging, Radiance, \n Image of nature as: Fire(glow), 火 \n Phase as: Wood \n Later Heaven Direction as:South \n Ealier Heaven Direction as: East \n Family Relationship as: Second daughter')
%                 case 6
%                     fprintf('6, 110, ☱ 兌 duì, the Joyous, Open (reflection), \n Image of nature as: Lake(marsh),澤 \n Phase as: Metal \n Later Heaven Direction as:West \n Ealier Heaven Direction as: Southeast \n Family Relationship as: Third daughter')
%                 case 7
%                     fprintf('7, 111, ☰ 乾 qián, the Creative, (natural) Force, \n Image of nature as: Heaven(sky),天 \n Phase as: Metal \n Later Heaven Direction as:NorthWest \n Ealier Heaven Direction as: South \n Family Relationship as: Father')
%             end
        end

        % Button pushed function: AnalysisButton_2
        function AnalysisButton_2Pushed(app, event)
            app.DownGua = mod(str2num(app.Number2),8);
            app.DownGuaBinary = app.gua(app.DownGua,2);
            app.DownGuaTrigram = app.gua(app.DownGua,3);
            app.DownGuaCN = app.gua(app.DownGua,4);
            app.DownGuaEN = app.gua(app.DownGua,5);
            app.DownGuaMeaning = app.gua(app.DownGua,6);
            app.DownGuaNatureEN = app.gua(app.DownGua,7);
            app.DownGuaNatureCN = app.gua(app.DownGua,8);
            app.DownGuaPhase = app.gua(app.DownGua,9);
            app.DownGuaLH = app.gua(app.DownGua,10);
            app.DownGuaEH = app.gua(app.DownGua,11);
            app.DownGuaFamily = app.gua(app.DownGua,12);

            if app.Language == 'EN'
                app.TextArea_2.Value = 'Second step:  We compute the Downapp.gua. Please check the left panel for further analysis.'
                app.YaoMeaningNatureFamilyandetcTextAreaLabel.Text = 'Downapp.gua';
                app.YaoMeaningNatureFamilyandetcTextArea.Value = ['Taking your second 2-digit number, after mod calculation, we have the Downapp.gua valued', num2str(app.Number2), 'which can be binary noted as: ', app.DownGuaBinary, '.The computation is the same as the first step for app.UpGua. ', 'The original 爻 yáo of Downapp.gua is denoted as: ', app.DownGuaTrigram, '.', ...
                    'The meaning of it is: ', app.DownGuaMeaning, 'And the image of nature of it is: ', app.DownGuaNatureEN, 'Phase: ',  app.DownGuaPhase, 'Later Heaven Direction: ', app.DownGuaLH, 'Ealier Heaven Direction: ', app.DownGuaEH, 'Family Relationship: ', app.DownGuaFamily]
            elseif app.Language == 'CN'
                app.TextArea_2.Value = '第二步:  我们计算下卦. 请看左侧进一步分析.'
                app.YaoMeaningNatureFamilyandetcTextAreaLabel.Text = '下卦';
                app.YaoMeaningNatureFamilyandetcTextArea.Value = ['根据你给出的第二个2位数, 经过8卦取模运算后, 我们得到下卦值为：', num2str(app.Number2), '其二进制表示为: ', app.DownGuaBinary, '。目前为止的计算和第一步的上卦相同。 ', '原始的 爻 yáo 标记为: ', app.DownGuaTrigram, '.', ...
                    '其含义为: ', app.DownGuaMeaning, '其自然画像为: ', app.DownGuaNatureEN, '8卦象处于五行: ',  app.DownGuaPhase, '文王诠释座落于: ', app.DownGuaLH, '武王诠释座落于: ', app.DownGuaEH, '家庭角色为: ', app.DownGuaFamily]                
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.gua(8,:) = {'0', '000', '☷', '坤', 'kūn', 'the Receptive, Field', 'Ground(earth)', '地', 'Earth', 'Southwest', 'North', 'Mother'};
            app.gua(1,:) = {'1', '001', '☶', '艮', 'gèn', 'Keeping Still, Bound', 'Mountain', '山', 'Earth', 'Northeast', 'Northwest', 'Third son'};
            app.gua(2,:) = {'2', '010', '☵', '坎', 'kǎn', 'the Abysmal, Gorge', 'Water', '水','Water', 'North', 'West', 'Second son'};
            app.gua(3,:) = {'3', '011', '☴', '巽', 'xùn', 'the Gentle, Ground', 'Wind(air)', '风', 'Wood', 'Southest', 'Southwest', 'First daughter'};
            app.gua(4,:) = {'4', '100', '☳', '震', 'zhèn', 'the Arousing, Shake', 'Thunder', '雷', 'Wood', 'East', 'Northeast', 'First son'};
            app.gua(5,:) = {'5', '101', '☲', '離', 'lí', 'the Clinging, Radiance', 'Fire(glow)', '火', 'Wood', 'South', 'East', 'Second daughter'};
            app.gua(6,:) = {'6', '110', '☱', '兌', 'duì', 'the Joyous, Open (reflection)', 'Lake(marsh)', '澤',  'Metal',  'West', 'Southeast', 'Third daughter'};
            app.gua(7,:) = {'7', '111', '☰', '乾', 'qián', 'the Creative, (natural) Force', 'Heaven(sky)', '天', 'Metal', 'NorthWest', 'South', 'Father'};
            app.YaoMeaningNatureFamilyandetcTextArea.Value = app.gua(1,:);
            app.StartButton.Text = 'Counting...';
            n = 30;
%            t0 = tic;
            pause(n);
%            t1 = toc;
            app.StartButton.Text = [num2str(n),'s, Next: '];
        end

        % Button pushed function: AnalysisButton_3
        function AnalysisButton_3Pushed(app, event)
            app.Number3 = str2num(app.Number1)+str2num(app.Number2);
            app.BianGua = mod(app.Number3,6);
 %           app.BianGuaBinary0 = [app.UpGuaBinary,app.DownGuaBinary]
            app.BianGuaBinary0 = cat(2,app.UpGuaBinary{:},app.DownGuaBinary{:})
            app.BianGuaBinary1 = app.BianGuaBinary0
 %           app.BianGuaBinary1(app.BianGua) = num2str(~str2num(app.BianGuaBinary0(app.BianGua)));
            switch app.BianGua
                case 0
                    app.BianGuaBinary1(1) = num2str(~(str2num(app.BianGuaBinary0(1))));
                otherwise
                    temp = app.BianGuaBinary0(7-app.BianGua)
                    app.BianGuaBinary1(7-app.BianGua) = num2str(~(str2num(temp)));
            end

            switch app.BianGuaBinary1
                case '000000'
                    app.BianGuaHexagram = ['☷','☷'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Earth';
                    app.BUC = 'Earth(Utiity) generates Earth(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky'
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '土';
                        app.BUC = '土(用) 生 土(体) 代表大吉。'
                    end
                case '000001'
                    app.BianGuaHexagram = ['☷','☶'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Earth';     
                    app.BUC = 'Earth(Utiity) generates Earth(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '土';
                        app.BUC = '土(用) 生 土(体) 代表大吉。'
                    end                    
                case '000010'
                    app.BianGuaHexagram = ['☷','☵'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Water';     
                    app.BUC = 'Earth(Utiity) overcomes Water(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '水';
                        app.BUC = '土(用) 克 水(体) 代表大凶。'
                    end
                case '000011'
                    app.BianGuaHexagram = ['☷','☴'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Wood';     
                    app.BUC= 'Wood(Body) overcomes Earth(Utiity) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(体) 克 土(用) 代表小吉。'
                    end
                case '000100'
                    app.BianGuaHexagram = ['☷','☳'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Wood';     
                    app.BUC= 'Wood(Body) overcomes Earth(Utiity) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(体) 克 土(用) 代表小吉。'
                    end                    
                case '000101'
                    app.BianGuaHexagram = ['☷','☲'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Fire';     
                    app.BUC= 'Fire(Body) generates Earth(Utiity) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '火';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '火(体) 生 土(用) 代表小凶。'
                    end 
                case '000110'
                    app.BianGuaHexagram = ['☷','☱'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Metal';     
                    app.BUC= 'Earth(Utiity) generates Metal(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(用) 生 金(体) 代表大吉。'
                    end                     
                case '000111'
                    app.BianGuaHexagram = ['☷','☰'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Metal';     
                    app.BUC= 'Earth(Utiity) generates Metal(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(用) 生 金(体) 代表大吉。'
                    end                       
                case '001000'
                    app.BianGuaHexagram = ['☶','☷'];  
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Earth';     
                    app.BUC= 'Earth(Utiity) generates Earth(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(用) 生 土(体) 代表大吉。'
                    end                       
                case '001001'
                    app.BianGuaHexagram = ['☶','☶'];    
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Earth';     
                    app.BUC= 'Earth(Utiity) generates Earth(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(用) 生 土(体) 代表大吉。'
                    end                     
                case '001010'
                    app.BianGuaHexagram = ['☶','☷'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Water';     
                    app.BUC= 'Earth(Utiity) overcomes Water(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '水';
                        app.BUC= '土(用) 生 水(体) 代表大凶。'
                    end                       
                case '001011'
                    app.BianGuaHexagram = ['☶','☴'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Wood';     
                    app.BUC= 'Wood(Body) overcomes Earth(Utiity) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(体) 克 土(用) 代表小吉。'
                    end                       
                case '001100'
                    app.BianGuaHexagram = ['☶','☳'];  
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Wood';     
                    app.BUC= 'Wood(Body) overcomes Earth(Utiity) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(体) 克 土(用) 代表小吉。'
                    end               
                case '001101'
                    app.BianGuaHexagram = ['☶','☲'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Fire';     
                    app.BUC= 'Fire(Body) generates Earth(Utiity) which means Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '火';
                        app.BUC= '火(体) 生 土(用) 代表小凶。'
                    end                           
                case '001110'
                    app.BianGuaHexagram = ['☶','☱'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Metal';     
                    app.BUC= 'Earth(Body) generates Metal(Utiity) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '土(用) 生 金(体) 代表大吉。'
                    end                     
                case '001111'
                    app.BianGuaHexagram = ['☶','☰'];
                    app.BianGuaPhase0= 'Earth';
                    app.BianGuaPhase1 = 'Metal';     
                    app.BUC= 'Earth(Body) generates Metal(Utiity) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '土';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '土(用) 生 金(体) 代表大吉。'
                    end                       
                case '010000'
                    app.BianGuaHexagram = ['☵','☷'];  
                    app.BianGuaPhase0= 'Water';
                    app.BianGuaPhase1 = 'Earth';     
                    app.BUC= 'Earth(Body) overcomes Water(Utiity) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '水';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(体) 克 水(用) 代表小吉。'
                    end                      
                case '010001'
                    app.BianGuaHexagram = ['☵','☷'];   
                    app.BianGuaPhase0= 'Water';
                    app.BianGuaPhase1 = 'Earth';     
                    app.BUC= 'Earth(Body) overcomes Water(Utiity) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '水';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(体) 克 水(用) 代表小吉。'
                    end                        
                case '010010'
                    app.BianGuaHexagram = ['☵','☵'];
                    app.BianGuaPhase0= 'Water';
                    app.BianGuaPhase1 = 'Water';     
                    app.BUC= 'Water(Utiity) generates Water(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '水';
                        app.BianGuaPhase1 = '水';
                        app.BUC= '水(体) 生 水(用) 代表大吉。'
                    end                     
                case '010011'
                    app.BianGuaHexagram = ['☵','☴'];
                    app.BianGuaPhase0= 'Water';
                    app.BianGuaPhase1 = 'Wood';     
                    app.BUC= 'Water(Utiity) generates Wood(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '水';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '水(体) 生 木(用) 代表大吉。'
                    end                          
                case '010100'
                    app.BianGuaHexagram = ['☵','☳'];  
                    app.BianGuaPhase0= 'Water';
                    app.BianGuaPhase1 = 'Wood';     
                    app.BUC= 'Water(Utiity) generates Wood(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '水';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '水(体) 生 木(用) 代表大吉。'
                    end                       
                case '010101'
                    app.BianGuaHexagram = ['☵','☲'];
                    app.BianGuaPhase0= 'Water';
                    app.BianGuaPhase1 = 'Fire';     
                    app.BUC= 'Water(Utiity) overcomes Fire(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '水';
                        app.BianGuaPhase1 = '火';
                        app.BUC= '水(用) 克 火(体) 代表大凶。'
                    end      
                case '010110'
                    app.BianGuaHexagram = ['☵','☱'];
                    app.BianGuaPhase0= 'Water';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Body) overcomes Water(Utiity) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '水';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 克 水(用) 代表小吉。'
                    end                        
                case '010111'
                    app.BianGuaHexagram = ['☵','☰'];
                    app.BianGuaPhase0= 'Water';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Body) overcomes Water(Utiity) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '水';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 克 水(用) 代表小吉。'
                    end                       
                case '011000'
                    app.BianGuaHexagram = ['☴','☷'];  
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Wood(Utiity) overcomes Earth(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '木(体) 克 土(用) 代表大凶。'
                    end                      
                case '011001'
                    app.BianGuaHexagram = ['☴','☶'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Wood(Utiity) overcomes Earth(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '木(体) 克 土(用) 代表大凶。'
                    end                      
                case '011010'
                    app.BianGuaHexagram = ['☴','☵'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Water';      
                    app.BUC= 'Water(Body) generates Wood(Utiity) which means Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '水';
                        app.BUC= '木(体) 生 水(用) 代表小凶。'
                    end                              
                case '011011'
                    app.BianGuaHexagram = ['☴','☴'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Wood(Utility) generates Wood(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(用) 生 木(体) 代表大吉。'
                    end                        
                case '011100'
                    app.BianGuaHexagram = ['☴','☳'];  
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Wood(Utility) generates Wood(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(用) 生 木(体) 代表大吉。'
                    end 
                case '011101'
                    app.BianGuaHexagram = ['☴','☳'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Fire';      
                    app.BUC= 'Wood(Utility) generates Fire(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '火';
                        app.BUC= '木(用) 生 火(体) 代表大吉。'
                    end                     
                case '011110'
                    app.BianGuaHexagram = ['☴','☷'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Body) overcomes Wood(Utility) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 克 木(用) 代表小吉。'
                    end                    
                case '011111'
                    app.BianGuaHexagram = ['☴','☷']; 
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Body) overcomes Wood(Utility) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 克 木(用) 代表小吉。'
                    end                      
                case '100000'
                    app.BianGuaHexagram = ['☳','☷'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Wood(Utiity) overcomes Earth(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '木(用) 克 土(体) 代表大凶。'
                    end                      
                case '100001'
                    app.BianGuaHexagram = ['☳','☶'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Wood(Utiity) overcomes Earth(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '木(用) 克 土(体) 代表大凶。'
                    end                        
                case '100010'
                    app.BianGuaHexagram = ['☳','☵'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Water';      
                    app.BUC= 'Water(Body) generates Wood(Utiity) which means Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '水';
                        app.BUC= '木(体) 生 水(用) 代表小凶。'
                    end                   
                case '100011'
                    app.BianGuaHexagram = ['☳','☴'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Wood(Utility) generates Wood(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(用) 生 木(体) 代表大吉。'
                    end                     
                case '100100'
                    app.BianGuaHexagram = ['☳','☳'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Wood(Utility) generates Wood(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(用) 生 木(体) 代表大吉。'
                    end                     
                case '100101'
                    app.BianGuaHexagram = ['☳','☲'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Fire';      
                    app.BUC= 'Wood(Utility) generates Fire(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '火';
                        app.BUC= '木(用) 生 火(体) 代表大吉。'
                    end                     
                case '100110'
                    app.BianGuaHexagram = ['☳','☱'];
                    app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Body) overcomes Wood(Utility) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 克 木(用) 代表小吉。'
                    end                      
                case '100111'
                    app.BianGuaHexagram = ['☳','☰'];
                     app.BianGuaPhase0= 'Wood';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Body) overcomes Wood(Utility) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '木';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 克 木(用) 代表小吉。'
                    end                     
                case '101000'
                    app.BianGuaHexagram = ['☲','☷'];  
                    app.BianGuaPhase0= 'Fire';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Fire(Utility) generates Earth(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '火';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '火(用) 生 土(体) 代表大吉。'
                    end                       
                case '101001'
                    app.BianGuaHexagram = ['☲','☳']; 
                    app.BianGuaPhase0= 'Fire';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Fire(Utility) generates Earth(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '火';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '火(用) 生 土(体) 代表大吉。'
                    end                       
                case '101010'
                    app.BianGuaHexagram = ['☲','☵'];
                    app.BianGuaPhase0= 'Fire';
                    app.BianGuaPhase1 = 'Water';      
                    app.BUC= 'Water(Body) overcomes Fire(Utility) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '火';
                        app.BianGuaPhase1 = '水';
                        app.BUC= '水(体) 克 火(用) 代表小吉。'
                    end                       
                case '101011'
                    app.BianGuaHexagram = ['☲','☴'];
                    app.BianGuaPhase0= 'Fire';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Wood(Body) generates Fire(Utility) which means Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '火';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(体) 生 火(用) 代表小凶。'
                    end                        
                case '101100'
                    app.BianGuaHexagram = ['☲','☳'];  
                    app.BianGuaPhase0= 'Fire';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Wood(Body) generates Fire(Utility) which means Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '火';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '木(体) 生 火(用) 代表小凶。'    
                    end
                case '101101'
                    app.BianGuaHexagram = ['☲','☲'];
                    app.BianGuaPhase0= 'Fire';
                    app.BianGuaPhase1 = 'Fire';      
                    app.BUC= 'Fire(Utility) generates Fire(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '火';
                        app.BianGuaPhase1 = '火';
                        app.BUC= '火(用) 生 火(体) 代表大吉。'    
                    end                   
                case '101110'
                    app.BianGuaHexagram = ['☲','☱'];
                    app.BianGuaPhase0= 'Fire';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Fire(Utility) overcomes Metal(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '火';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '火(用) 克 金(体) 代表大凶。'    
                    end                         
                case '101111'
                    app.BianGuaHexagram = ['☲','☰'];
                    app.BianGuaPhase0= 'Fire';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Fire(Utility) overcomes Metal(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '火';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '火(用) 克 金(体) 代表大凶。'    
                    end                       
                case '110000'
                    app.BianGuaHexagram = ['☴','☷'];  
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Earth(Body) generates Metal(Utility) which means Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(体) 生 金(用) 代表小凶。'    
                    end                      
                case '110001'
                    app.BianGuaHexagram = ['☴','☶'];     
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Earth(Body) generates Metal(Utility) which means Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(体) 生 金(用) 代表小凶。'    
                    end                         
                case '110010'
                    app.BianGuaHexagram = ['☴','☵'];
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Water';      
                    app.BUC= 'Metal(Utility) generates Water(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '水';
                        app.BUC= '金(用) 生 水(体) 代表大吉。'    
                    end                         
                case '110011'
                    app.BianGuaHexagram = ['☴','☴'];
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Metal(Utility) overcomes Wood(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '金(用) 克 木(体) 代表大凶。'    
                    end                      
                case '110100'
                    app.BianGuaHexagram = ['☴','☳'];  
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Metal(Utility) overcomes Wood(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '金(用) 克 木(体) 代表大凶。'        
                    end
                case '110101'
                    app.BianGuaHexagram = ['☴','☲'];
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Fire';      
                    app.BUC= 'Fire(Body) overcomes Metal(Utility) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '火';
                        app.BUC= '火(体) 克 金(用) 代表小吉。'    
                    end                    
                case '110110'
                    app.BianGuaHexagram = ['☴','☱'];
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Utility) generates Metal(Utility) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 生 金(用) 代表大吉。'    
                    end                         
                case '110111'
                    app.BianGuaHexagram = ['☴','☰'];
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Utility) generates Metal(Utility) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 生 金(用) 代表大吉。'    
                    end                       
                case '111000'
                    app.BianGuaHexagram = ['☰','☷']; 
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Earth(Body) generates Metal(Utility) which means Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(体) 生 金(用) 代表小凶。'    
                    end                         
                case '111001'
                    app.BianGuaHexagram = ['☰','☶'];  
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Earth';      
                    app.BUC= 'Earth(Body) generates Metal(Utility) which means Miserable.'
                    app.ResultKnob.Value = 'Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '土';
                        app.BUC= '土(体) 生 金(用) 代表小凶。'    
                    end                         
                case '111010'
                    app.BianGuaHexagram = ['☰','☵'];
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Water';      
                    app.BUC= 'Metal(Utility) generates Water(Body) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '水';
                        app.BUC= '金(用) 生 水(体) 代表大吉。'    
                    end                      
                case '111011'
                    app.BianGuaHexagram = ['☰','☴'];
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Metal(Utility) overcomes Wood(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '金(用) 克 木(体) 代表大凶。'     
                    end
                case '111100'
                    app.BianGuaHexagram = ['☰','☳'];  
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Wood';      
                    app.BUC= 'Metal(Utility) overcomes Wood(Body) which means Severe Miserable.'
                    app.ResultKnob.Value = 'Severe Miserable' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '木';
                        app.BUC= '金(用) 克 木(体) 代表大凶。'    
                    end
                case '111101'
                    app.BianGuaHexagram = ['☰','☲'];
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Fire';      
                    app.BUC= 'Fire(Body) overcomes Metal(Utility) which means Lucky.'
                    app.ResultKnob.Value = 'Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '火';
                        app.BUC= '火(体) 克 金(用) 代表小吉。'    
                    end
                case '111110'
                    app.BianGuaHexagram = ['☰','☱'];
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Utility) generates Metal(Utility) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 生 金(用) 代表大吉。'    
                    end                       
                case '111111'
                    app.BianGuaHexagram = ['☰','☰'];      
                    app.BianGuaPhase0= 'Metal';
                    app.BianGuaPhase1 = 'Metal';      
                    app.BUC= 'Metal(Utility) generates Metal(Utility) which means Super Lucky.'
                    app.ResultKnob.Value = 'Super Lucky' 
                    if app.Language == 'CN'
                        app.BianGuaPhase0= '金';
                        app.BianGuaPhase1 = '金';
                        app.BUC= '金(体) 生 金(用) 代表大吉。'    
                    end                       
            end
            if app.Language == 'EN'
                app.TextArea_3.Value = 'Third step:  We compute the Change(app.BianGua). Please check the left panel for further analysis.'
                app.YaoMeaningNatureFamilyandetcTextAreaLabel.Text = 'app.BianGua(Change)';
                app.YaoMeaningNatureFamilyandetcTextArea.Value = ['Taking sum of your 2-digit numbers, after mod calculation, we have the app.BianGua valued', num2str(app.BianGua), 'which can be binary noted as: ', app.BianGuaBinary1, '.The mod computation is the similar as the app.UpGua and Downapp.gua. ', ...
                    'That is to say, to change with the current situation according to what you want, the efforts will be made onto the ', num2str(app.BianGua), 'th Yao among the all six. From 0 to 1(Yin to Yang) or 1 to 0(Yang to Yin), the updated trigrams are denoted as: ', app.BianGuaHexagram(1), 'and the final hexagram combined becomes: ', app.BianGuaHexagram(2)]
            elseif app.Language == 'CN' 
                app.TextArea_3.Value = '第三部: 我们计算动爻（变卦）。 请看左侧具体分析。'
                app.YaoMeaningNatureFamilyandetcTextAreaLabel.Text = '动爻（变卦）';
                app.YaoMeaningNatureFamilyandetcTextArea.Value = ['将你给出的两个2位数取和, 经过6爻取模运算后, 我们得到了动爻（变卦）为：', num2str(app.BianGua), 'w其2进制表示为: ', app.BianGuaBinary1, '。所谓变卦就是揭示变化所在。 ', ...
                    '也就是说, 根据你的所求在现实中的情况,变化坐落于第', num2str(app.BianGua), '爻 Yao（总爻数为6）. 从 0 变为 1 (即从阴至阳) 或是 1 变为 0 (即从阳至阴), 变化后,最后的 完整卦变为: ', app.BianGuaHexagram]               
            end
        end

        % Button pushed function: AnalysisButton_4
        function AnalysisButton_4Pushed(app, event)
            if app.Language == 'EN'
                app.TextArea_4.Value = 'Last step:  We compute the Result after app.BianGua. Please check the left panel for further analysis.'
                app.YaoMeaningNatureFamilyandetcTextAreaLabel.Text = 'Result';
                app.YaoMeaningNatureFamilyandetcTextArea.Value = ['As the most important 2 concepts related here are the Phase of nature(Wu Xing) and the causality(relations between body and utility), we recap them of the updated app.UpGua and Downapp.gua. The Phase of Nature of app.UpGua(Utility) is: ', app.BianGuaPhase0, 'while the phase of Nature of Downapp.gua(Body) is: ', app.BianGuaPhase1, ...
                    'Thus, check the Wu Xing diagram, we get the result of ', app.BUC]
            elseif app.Language == 'CN'  
                app.TextArea_4.Value = '最后一步:  我们推算最后的凶吉结果. 请看左侧具体分析.'
                app.YaoMeaningNatureFamilyandetcTextAreaLabel.Text = '结果';
                app.YaoMeaningNatureFamilyandetcTextArea.Value = ['这里用于推算的2大重要概念为卦象所在的五行以及体（下卦）用（上卦）相生相克关系。根据变卦调整后，上卦的相的五行: ', app.BianGuaPhase0, '下卦象的五行: ', app.BianGuaPhase1, ...
                    '查询五行相生相克关系图, 最后结果为： ', app.BUC]
            end                
        end

        % Value changed function: LanguageSwitch
        function LanguageSwitchValueChanged(app, event)
            value = app.LanguageSwitch.Value;
            app.Language = value;
            if value == 'CN'
                app.Step0Closeyoureyesandmeditatefor30secondsLabel.Text = '起始步骤0:请闭上双眼30秒询问你想问的问题，并给出两个2位数： '
                app.FlowerMaysNumbersfromIchingLabel.Text = '易经之梅花易数'
                app.FurtherInterpretationLabel.Text = '进一步分析'
                app.TextArea.Value = '易经有,太极 (太極; Taiji)生两仪,  (兩儀; L iangyi)，两仪生四象(四象; Sixiang)，四象生八卦(八卦; Baapp.gua)。'
                app.TextArea_2.Value = '阴阳由虚实线表示: 阴 (⚋) 阳 (⚊)。 3阴3阳组合为6爻 (八卦) 名为, Qian (乾, ☰), Dui (兌, ☱), Li (離, ☲), Zhen (震, ☳), Xun (巽, ☴), Kan (坎, ☵), Gen (艮, ☶), and Kun (坤, ☷).'
                app.TextArea_3.Value = 'Qian (乾, ☰), Dui (兌, ☱), Li (離, ☲), Zhen (震, ☳), Xun (巽, ☴), Kan (坎, ☵), Gen (艮, ☶), and Kun (坤, ☷), 6爻所示的上下卦相合后即为完整的卦（变卦前的原始卦象）。'
                app.TextArea_4.Value = '五行: 金: Qian (乾, ☰), Dui (兌, ☱)；火: Li (離, ☲)； 木: Zhen (震, ☳), Xun (巽, ☴), Water: Kan (坎, ☵)； 土: Gen (艮, ☶) and Kun (坤, ☷). 大吉出于用生体，而大凶源于用克体。小吉出于体克用，而小吉出于体生用。（我们将用体同行定为大吉）'
                app.PreludeTextArea = '易经用于研究变化, 始于周朝. 其在决策中的运用广泛于 儒学, 道学 以及佛学。八卦结合阴阳五行不仅用于占卜星象，风水，也用于各种运势占卜。'
                app.KeepinmindTextArea = '上卦(第一个数字相关)为用(动属性) 二下卦(第二个数字相关)为体(静属性)。变化揭示于第三卦（变卦的动爻）并最后根据变化后的体用相生相克关系得出凶吉推断。'
            end
        end

        % Callback function: CenterPanel, CenterPanel
        function CenterPanelButtonDown(app, event)
%            app.gua = repmat('0',8,12);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FurtherInterpretationLabel = uilabel(app.LeftPanel);
            app.FurtherInterpretationLabel.Text = 'Further Interpretation';
            app.YaoMeaningNatureFamilyandetcTextAreaLabel = uilabel(app.LeftPanel);
            app.YaoMeaningNatureFamilyandetcTextAreaLabel.Text = 'Yao, Meaning, Nature, Family and etc.';
            app.YaoMeaningNatureFamilyandetcTextArea = uitextarea(app.LeftPanel);
            app.Hyperlink2 = uihyperlink(app.LeftPanel);
            app.Hyperlink2.URL = 'https://en.wikipedia.org/wiki/Wen_Wang_Gua#Mei_Hua_Yi';
            app.Hyperlink2.Text = 'Wen Wang Gua';
            app.Hyperlink2_2 = uihyperlink(app.LeftPanel);
            app.Hyperlink2_2.URL = 'https://muse.jhu.edu/article/591609';
            app.Hyperlink2_2.Text = 'Leibniz';
            app.KeepinmindTextAreaLabel = uilabel(app.LeftPanel);
            app.KeepinmindTextAreaLabel.Text = 'Keep in mind:';
            app.KeepinmindTextArea = uitextarea(app.LeftPanel);
            app.KeepinmindTextArea.Value = {'UpGua(first trigram) is regarded as Utility(Dynamic) and the DownGua(second trigram) is regarded as Body(Static). '; 'After the third step Dong Yao, the change happens and finally it will get us to the result(the causality of body and utility is important) based on the five nature elemets of the changed Hexagram.'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.SizeChangedFcn = createCallbackFcn(app, @CenterPanelButtonDown, true);
            app.CenterPanel.ButtonDownFcn = createCallbackFcn(app, @CenterPanelButtonDown, true);
            app.FlowerMaysNumbersfromIchingLabel = uilabel(app.CenterPanel);
            app.FlowerMaysNumbersfromIchingLabel.Text = 'FlowerMay‘s Numbers from Iching';
            app.Pleaseinputtwo2digitnumbersEditFieldLabel = uilabel(app.CenterPanel);
            app.Pleaseinputtwo2digitnumbersEditFieldLabel.Text = 'Please input two 2-digit numbers ';
            app.Pleaseinputtwo2digitnumbersEditField = uieditfield(app.CenterPanel, 'text');
            app.Pleaseinputtwo2digitnumbersEditField.ValueChangedFcn = createCallbackFcn(app, @Pleaseinputtwo2digitnumbersEditFieldValueChanged, true);
            app.Pleaseinputtwo2digitnumbersEditField.Value = '17';
            app.Step0Closeyoureyesandmeditatefor30secondsLabel = uilabel(app.CenterPanel);
            app.Step0Closeyoureyesandmeditatefor30secondsLabel.Text = 'Step 0: Close your eyes and meditate 30s for two 2-digit numbers :';
            app.Pleaseinputtwo2digitnumbersEditField_2 = uieditfield(app.CenterPanel, 'text');
            app.Pleaseinputtwo2digitnumbersEditField_2.ValueChangedFcn = createCallbackFcn(app, @Pleaseinputtwo2digitnumbersEditField_2ValueChanged, true);
            app.Pleaseinputtwo2digitnumbersEditField_2.Value = '18';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Step1Tab = uitab(app.TabGroup);
            app.Step1Tab.Title = 'Step1';
            app.TextArea = uitextarea(app.Step1Tab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'In Iching, there is the Supreme Polarity, (太極; Taiji), which generates the Two Modes. (兩儀; Liangyi)'; 'The Two Modes generate the Four Images, (四象; Sixiang)'; 'and the Four Images generate the Eight Trigrams. (八卦; Bagua). '};
            app.Image = uiimage(app.Step1Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'FlowerMay', 'images1.jpg');
            app.AnalysisButton = uibutton(app.Step1Tab, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.Step2Tab = uitab(app.TabGroup);
            app.Step2Tab.Title = 'Step2';
            app.TextArea_2 = uitextarea(app.Step2Tab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Yin and yang are represented by broken and solid lines: yin is broken (⚋) and yang is solid (⚊). Different constructions of three yin and yang lines lead to eight trigrams (八卦) namely, Qian (乾, ☰), Dui (兌, ☱), Li (離, ☲), Zhen (震, ☳), Xun (巽, ☴), Kan (坎, ☵), Gen (艮, ☶), and Kun (坤, ☷). '};
            app.Image_2 = uiimage(app.Step2Tab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'FlowerMay', 'images1.jpg');
            app.AnalysisButton_2 = uibutton(app.Step2Tab, 'push');
            app.AnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButton_2Pushed, true);
            app.AnalysisButton_2.Text = 'Analysis';
            app.Step3Tab = uitab(app.TabGroup);
            app.Step3Tab.Title = 'Step3';
            app.TextArea_3 = uitextarea(app.Step3Tab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'As we have known about the eight trigrams (八卦) namely, Qian (乾, ☰), Dui (兌, ☱), Li (離, ☲), Zhen (震, ☳), Xun (巽, ☴), Kan (坎, ☵), Gen (艮, ☶), and Kun (坤, ☷), it''s time to go to the combination of Upgua and Downgua. Two trigrams give us a hexagram.'};
            app.Image_3 = uiimage(app.Step3Tab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'FlowerMay', 'image5.2.png');
            app.AnalysisButton_3 = uibutton(app.Step3Tab, 'push');
            app.AnalysisButton_3.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButton_3Pushed, true);
            app.AnalysisButton_3.Text = 'Analysis';
            app.Step4Tab = uitab(app.TabGroup);
            app.Step4Tab.Title = 'Step4';
            app.TextArea_4 = uitextarea(app.Step4Tab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Wu Xing: '; 'Metal: Qian (乾, ☰), Dui (兌, ☱),'; 'Fire: Li (離, ☲), '; 'Wooden: Zhen (震, ☳), Xun (巽, ☴), Water: Kan (坎, ☵), '; 'Earth: Gen (艮, ☶) and Kun (坤, ☷). '; 'Super Lucky is when Utility generates Body while Sever Miserable is when Utility overcomes Body. '; 'Lucky is when Body overcomes Utility whilie Miserable is when Body generates Utility.'};
            app.Image_4 = uiimage(app.Step4Tab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'FlowerMay', '300px-Wu_Xing.png');
            app.AnalysisButton_4 = uibutton(app.Step4Tab, 'push');
            app.AnalysisButton_4.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButton_4Pushed, true);
            app.AnalysisButton_4.Text = 'Analysis';
            app.ResultKnobLabel = uilabel(app.CenterPanel);
            app.ResultKnobLabel.Text = 'Result';
            app.ResultKnob = uiknob(app.CenterPanel, 'discrete');
            app.ResultKnob.Items = {'Super Lucky', 'Lucky', 'Miserable', 'Severe Miserable'};
            app.ResultKnob.Value = 'Super Lucky';
            app.StartButton = uibutton(app.CenterPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            app.Hyperlink = uihyperlink(app.RightPanel);
            app.Hyperlink.URL = 'https://en.wikipedia.org/wiki/I_Ching#Hexagrams';
            app.Hyperlink.Text = 'I Ching';
            app.LanguageSwitchLabel = uilabel(app.RightPanel);
            app.LanguageSwitchLabel.Text = 'Language';
            app.LanguageSwitch = uiswitch(app.RightPanel, 'slider');
            app.LanguageSwitch.Items = {'EＮ', 'CＮ'};
            app.LanguageSwitch.ValueChangedFcn = createCallbackFcn(app, @LanguageSwitchValueChanged, true);
            app.LanguageSwitch.Value = 'EＮ';
            app.PreludeTextAreaLabel = uilabel(app.RightPanel);
            app.PreludeTextAreaLabel.Text = 'Prelude';
            app.PreludeTextArea = uitextarea(app.RightPanel);
            app.PreludeTextArea.Editable = 'off';
            app.PreludeTextArea.Value = {'I Ching is the classic of Changes, derived from Zhou dynasty. It has been used in decision making by  Confucianism, Taoism and Buddhism. '; 'The hexagrams themselves have often acquired cosmological significance and well known as traditional names for the processes of change such as yin and yang and Wu Xing.'};
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'FlowerMay', 'images3.jpg');
            app.Hyperlink_2 = uihyperlink(app.RightPanel);
            app.Hyperlink_2.URL = 'https://en.wikipedia.org/wiki/Yin_and_yang';
            app.Hyperlink_2.Text = 'Yin Yang';
            app.PrerequisitLabel = uilabel(app.RightPanel);
            app.PrerequisitLabel.Text = 'Pre-requisit';
            app.Hyperlink_3 = uihyperlink(app.RightPanel);
            app.Hyperlink_3.URL = 'https://en.wikipedia.org/wiki/Bagua';
            app.Hyperlink_3.Text = 'Eight Trigram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeiHuaYiShu1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end